classdef model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Label_2                      matlab.ui.control.Label
        HalflifeEditField            matlab.ui.control.NumericEditField
        HalflifeEditFieldLabel       matlab.ui.control.Label
        DecayconstantEditField       matlab.ui.control.NumericEditField
        DecayconstantEditFieldLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        ResultLabel                  matlab.ui.control.Label
        CalculatetheenergyButton     matlab.ui.control.Button
        AtomicnumberZSpinner         matlab.ui.control.Spinner
        AtomicnumberZSpinnerLabel    matlab.ui.control.Label
        MassnumberASpinner           matlab.ui.control.Spinner
        MassnumberASpinnerLabel      matlab.ui.control.Label
        AlphaDecayEnergyCalculatorLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DecayconstantEditField
        function DecayconstantEditFieldValueChanged(app, event)
            value = app.DecayconstantEditField.Value;
            app.HalflifeEditField.Value = log(2) / value;
        end

        % Value changed function: HalflifeEditField
        function HalflifeEditFieldValueChanged(app, event)
            value = app.HalflifeEditField.Value;
            app.DecayconstantEditField.Value = log(2) / value;
        end

        % Button pushed function: CalculatetheenergyButton
        function CalculatetheenergyButtonPushed(app, event)
            A = app.MassnumberASpinner.Value;
            Z = app.AtomicnumberZSpinner.Value;
            lambda = app.DecayconstantEditField.Value;
            C = consts();
            MeV = C.e * 1e6;

            result = model.solve(A, Z, lambda, 1000);
            app.Label.Text = "E = " + result + " MeV";
            app.Label_2.Text = "dm = " + result / C.c + " uma";

            range = (2:0.01:15);
            plot(app.UIAxes, range, model.decaycst(A, Z, range * MeV));
            hold(app.UIAxes, "on");
            plot(app.UIAxes, result, lambda, "ro");
            hold(app.UIAxes, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Decay constant as a function of energy released')
            xlabel(app.UIAxes, 'Energy (MeV)')
            ylabel(app.UIAxes, 'Lambda (Decay/s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.YScale = 'log';
            app.AlphaDecayEnergyCalculatorLabel = uilabel(app.UIFigure);
            app.AlphaDecayEnergyCalculatorLabel.Text = 'Alpha Decay Energy Calculator';
            app.MassnumberASpinnerLabel = uilabel(app.UIFigure);
            app.MassnumberASpinnerLabel.Text = 'Mass number (A)';
            app.MassnumberASpinner = uispinner(app.UIFigure);
            app.MassnumberASpinner.Limits = [8 400];
            app.MassnumberASpinner.RoundFractionalValues = 'on';
            app.MassnumberASpinner.Value = 212;
            app.AtomicnumberZSpinnerLabel = uilabel(app.UIFigure);
            app.AtomicnumberZSpinnerLabel.Text = 'Atomic number (Z)';
            app.AtomicnumberZSpinner = uispinner(app.UIFigure);
            app.AtomicnumberZSpinner.Limits = [4 200];
            app.AtomicnumberZSpinner.RoundFractionalValues = 'on';
            app.AtomicnumberZSpinner.Value = 84;
            app.CalculatetheenergyButton = uibutton(app.UIFigure, 'push');
            app.CalculatetheenergyButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatetheenergyButtonPushed, true);
            app.CalculatetheenergyButton.Text = 'Calculate the energy';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DecayconstantEditFieldLabel = uilabel(app.UIFigure);
            app.DecayconstantEditFieldLabel.Text = 'Decay constant';
            app.DecayconstantEditField = uieditfield(app.UIFigure, 'numeric');
            app.DecayconstantEditField.ValueChangedFcn = createCallbackFcn(app, @DecayconstantEditFieldValueChanged, true);
            app.DecayconstantEditField.Value = 2310490.60186648;
            app.HalflifeEditFieldLabel = uilabel(app.UIFigure);
            app.HalflifeEditFieldLabel.Text = 'Half life';
            app.HalflifeEditField = uieditfield(app.UIFigure, 'numeric');
            app.HalflifeEditField.ValueChangedFcn = createCallbackFcn(app, @HalflifeEditFieldValueChanged, true);
            app.HalflifeEditField.Value = 3e-07;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end