classdef secant < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        IterationscountLabel            matlab.ui.control.Label
        ImageerrorLabel                 matlab.ui.control.Label
        AbsoluteerrorLabel              matlab.ui.control.Label
        RelativeerrorLabel              matlab.ui.control.Label
        UpperboundEditField             matlab.ui.control.NumericEditField
        UpperboundEditFieldLabel        matlab.ui.control.Label
        LowerboundEditField             matlab.ui.control.NumericEditField
        LowerboundEditFieldLabel        matlab.ui.control.Label
        Initialguess2EditField          matlab.ui.control.NumericEditField
        Initialguess2EditFieldLabel     matlab.ui.control.Label
        Initialguess1EditField          matlab.ui.control.NumericEditField
        Initialguess1EditFieldLabel     matlab.ui.control.Label
        ResultLabel                     matlab.ui.control.Label
        FindrootButton                  matlab.ui.control.Button
        fxEditField                     matlab.ui.control.EditField
        fxEditFieldLabel                matlab.ui.control.Label
        SecantMethodSolverLabel         matlab.ui.control.Label
        CriterionValueEditField         matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        StoppingcriterionDropDown       matlab.ui.control.DropDown
        StoppingcriterionDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CriterionValueEditField
        function CriterionValueEditFieldValueChanged(app, event)
            value = app.CriterionValueEditField.Value;
            if app.StoppingcriterionDropDown.Value == "Iterations count", app.CriterionValueEditField.Value = round(value); end
        end

        % Value changed function: StoppingcriterionDropDown
        function StoppingcriterionDropDownValueChanged(app, event)
            value = app.CriterionValueEditField.Value;
            if app.StoppingcriterionDropDown.Value == "Iterations count", app.CriterionValueEditField.Value = round(value); end
        end

        % Button pushed function: FindrootButton
        function FindrootButtonPushed(app, event)
            syms x;
            func = matlabFunction(str2sym(app.fxEditField.Value));

            stop = @(a, b, c, d) false;
            mode = app.StoppingcriterionDropDown.Value;
            error = app.CriterionValueEditField.Value;
            if mode == "Absolute error",
                stop = secant.stop_converge(error);
            elseif mode == "Relative error",
                stop = secant.stop_relative(error);
            elseif mode == "Image error",
                stop = secant.stop_error(error);
            elseif mode == "Iterations count",
                stop = secant.stop_terms(error);
            end

            top = app.UpperboundEditField.Value;
            low = app.LowerboundEditField.Value;

            if low > top,
                app.UpperboundEditField.Value = low;
                app.LowerboundEditField.Value = top;
                top = app.UpperboundEditField.Value;
                low = app.LowerboundEditField.Value;
            end

            x0 = app.Initialguess1EditField.Value;
            x1 = app.Initialguess2EditField.Value;
            x0 = min(max(x0, low), top);
            x1 = min(max(x1, low), top);
            app.Initialguess1EditField.Value = x0;
            app.Initialguess2EditField.Value = x1;

            [result, result2, count] = secant.apply(func, x0, x1, stop, [low, top]);

            format long;
            app.ResultLabel.Text = "Result: " + result;
            app.AbsoluteerrorLabel.Text = "Absolute error: " + abs(result2 - result);
            app.RelativeerrorLabel.Text = "Relative error: " + abs(result2 - result) / abs(result);
            app.ImageerrorLabel.Text = "Image error: " + func(result);
            app.IterationscountLabel.Text = "Iterations count: " + count;

            plotmin = max(result - 10, low);
            plotmax = min(result + 10, top);
            range = plotmin:0.01:plotmax;
            plot(app.UIAxes, range, func(range));
            hold(app.UIAxes, "on");
            plot(app.UIAxes, result, func(result), "ro");
            plot(app.UIAxes, range, zeros(size(range)), "k--");
            hold(app.UIAxes, "off");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'f(x)')
            zlabel(app.UIAxes, 'Z')
            app.StoppingcriterionDropDownLabel = uilabel(app.UIFigure);
            app.StoppingcriterionDropDownLabel.Text = 'Stopping criterion';
            app.StoppingcriterionDropDown = uidropdown(app.UIFigure);
            app.StoppingcriterionDropDown.Items = {'Relative error', 'Absolute error', 'Image error', 'Iterations count', 'Completely converge'};
            app.StoppingcriterionDropDown.ValueChangedFcn = createCallbackFcn(app, @StoppingcriterionDropDownValueChanged, true);
            app.StoppingcriterionDropDown.Value = 'Iterations count';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Criterion Value';
            app.CriterionValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.CriterionValueEditField.Limits = [0 Inf];
            app.CriterionValueEditField.ValueChangedFcn = createCallbackFcn(app, @CriterionValueEditFieldValueChanged, true);
            app.CriterionValueEditField.Value = 10;
            app.SecantMethodSolverLabel = uilabel(app.UIFigure);
            app.SecantMethodSolverLabel.Text = 'Secant Method Solver';
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x) =';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = 'x^5/540 + x^4/140 - x^3/10 + x^2/7 - x + 1';
            app.FindrootButton = uibutton(app.UIFigure, 'push');
            app.FindrootButton.ButtonPushedFcn = createCallbackFcn(app, @FindrootButtonPushed, true);
            app.FindrootButton.Text = 'Find root';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result:';
            app.Initialguess1EditFieldLabel = uilabel(app.UIFigure);
            app.Initialguess1EditFieldLabel.Text = 'Initial guess 1';
            app.Initialguess1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Initialguess1EditField.Value = 4;
            app.Initialguess2EditFieldLabel = uilabel(app.UIFigure);
            app.Initialguess2EditFieldLabel.Text = 'Initial guess 2';
            app.Initialguess2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Initialguess2EditField.Value = 5;
            app.LowerboundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerboundEditFieldLabel.Text = 'Lower bound';
            app.LowerboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerboundEditField.Value = -2;
            app.UpperboundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperboundEditFieldLabel.Text = 'Upper bound';
            app.UpperboundEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperboundEditField.Value = 10;
            app.RelativeerrorLabel = uilabel(app.UIFigure);
            app.RelativeerrorLabel.Text = 'Relative error:';
            app.AbsoluteerrorLabel = uilabel(app.UIFigure);
            app.AbsoluteerrorLabel.Text = 'Absolute error:';
            app.ImageerrorLabel = uilabel(app.UIFigure);
            app.ImageerrorLabel.Text = 'Image error:';
            app.IterationscountLabel = uilabel(app.UIFigure);
            app.IterationscountLabel.Text = 'Iterations count:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = secant

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end